classdef monitor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        NameEditField       matlab.ui.control.EditField
        NameEditFieldLabel  matlab.ui.control.Label
        ConnectButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        % Structure
        season;            % Struct of each line following run
        hardward_setting;  % robot limit + calibration
        frame;             % Struct of input/output datas
        state;             % State params of each road
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: NameEditField
        function NameEditFieldValueChanged(app, event)
            value = app.NameEditField.Value;
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.Text = 'Connect';
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.NameEditField.Value = 'Behind the scream';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = monitor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end