classdef monitor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ConnectButton            matlab.ui.control.Button
        ControllerDropDown       matlab.ui.control.DropDown
        ControllerDropDownLabel  matlab.ui.control.Label
        RunModeDropDown          matlab.ui.control.DropDown
        RunModeDropDownLabel     matlab.ui.control.Label
        StopButton               matlab.ui.control.Button
        StartButton              matlab.ui.control.Button
        SaveButton               matlab.ui.control.Button
        LineAxes                 matlab.ui.control.UIAxes
        ErrorAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        net
        runLog
        frameBuf

        ctrlReg struct
        steerCtrlState
        tmr
        t0 double = 0;   % dùng tic/toc
        inTick logical = false; % chống re-entry

        stopFlag logical = true;
        frameIdx uint32 = 0
        rxFailCount uint16 = 0
        lastSteerDeg double = 0
        lastError double = 0
        plotDecim uint16 = 3
        driveDecim uint16 = 10
        appState        % trạng thái toàn cục
        roadRt          % trạng thái road hiện tại
        roadMap         % cấu hình các đoạn đường
        tuneDB          % database hệ số điều khiển
        cfg             % cấu hình chung

                
    end


    methods (Access = private)
        
        % Initialize app
        function startup(app)
        
            % ===== Init cfg =====
            app.cfg = struct();
            
            app.cfg.proto.CMD_MODE_OP   = uint8(hex2dec('FF'));
            app.cfg.proto.CMD_MODE_IDLE = uint8(hex2dec('FE'));
            app.cfg.proto.CMD_CTRL      = uint8(hex2dec('F1'));
            app.cfg.proto.CMD_STOP      = uint8(hex2dec('F0'));
            app.cfg.proto.ACK           = uint8(hex2dec('20'));
            
            app.cfg.timing.rxTimeoutS   = 0.2;
            app.cfg.timing.dtControlS   = 0.02;   % 50 Hz (phù hợp robot)
            
            app.cfg.line.offset    = [0 0 0 0 0];
            app.cfg.line.scale     = [1 1 1 1 1];
            app.cfg.line.threshold = 0.5 * ones(1,5);
            app.cfg.line.positions = [-2 -1 0 1 2];
            
            app.cfg.steer.minDeg    = 55;
            app.cfg.steer.maxDeg    = 105;
            app.cfg.steer.centerDeg = 75;
            
            app.cfg.drive.basePWM16 = uint16(40000); % test
            
            app.appState.lastLineMask = [0 0 0 0 0];
            

            % ===== ROADMAP INIT =====
            app.roadMap = cfg_roadMap(app.cfg);
            
            % ===== appState minimal fields for road test =====
            app.appState.currentRoadId = 1;
            app.appState.prevRoadId    = 1;
            app.appState.encTotal      = uint32(0);
            
            % Ensure ctrl struct exists
            if ~isfield(app.appState,"ctrl") || isempty(app.appState.ctrl)
                app.appState.ctrl = struct( ...
                    "speed_u16", uint16(0), ...
                    "steerDeg",  uint16(app.cfg.steer.centerDeg), ...
                    "cmd",       app.cfg.proto.CMD_CTRL );
            end
            
            % ===== road runtime =====
            app.roadRt = initRoadRuntime();
            app.roadRt.enterEncTotal = app.appState.encTotal;

            % ===== Frame buffer & log =====
            app.frameBuf = struct("frames", repmat(struct(), 1, 0));
            app.runLog   = struct("frames", repmat(struct(), 1, 0));
            
            % ===== Create Network =====
            app.net = capstone.io.Network(); % giữ nếu class này tồn tại

            disp("[monitor] startup OK");

            % ===== ROADMAP + ROAD STATE =====
            app.roadMap = cfg_roadMap(app.cfg);
            
            app.appState.currentRoadId = 1;
            app.appState.prevRoadId    = 1;
            
            % Encoder total tích lũy bên MATLAB (để lengthEnc hoạt động)
            app.appState.encTotal = uint32(0);
            
            % Ensure ctrl struct exists
            if ~isfield(app.appState,"ctrl") || isempty(app.appState.ctrl)
                app.appState.ctrl = struct( ...
                    "speed_u16", uint16(0), ...
                    "steerDeg",  uint16(app.cfg.steer.centerDeg), ...
                    "cmd",       app.cfg.proto.CMD_CTRL );
            end
            
            app.roadRt = initRoadRuntime();
            app.roadRt.enterEncTotal = app.appState.encTotal;
        end

        % Start timer
        % For every 1ms: The line following program is executed
        function startTimer(app)
            if isempty(app.tmr) || ~isvalid(app.tmr)
                    app.tmr = timer( ...
                        "ExecutionMode","fixedDelay", ...
                        "Period", app.cfg.timing.dtControlS, ...
                        "TimerFcn", @(~,~)app.tick() );
            end
            start(app.tmr);
        end

        % Stop timer
        function stopTimer(app)
            if ~isempty(app.tmr) && isvalid(app.tmr)
                stop(app.tmr);
            end
        end
        
        % Timer interrupt
        % Main program
        function tick(app)
            % ===== STOP CONDITIONS =====
            if app.stopFlag || app.t0 == 0
                return;
            end
            if isempty(app.net) || ~app.net.isConnected()
                return;
            end
        
            % ===== 1) RX SENSOR (blocking) =====
            [ok, meas] = app.net.recvSensorFrame(app.cfg.timing.rxTimeoutS);
            if ~ok
                app.rxFailCount = app.rxFailCount + 1;
                return;
            end
            app.rxFailCount = 0;
        
            % ===== 2) PROCESS LINE =====
            proc = struct();
            [proc.lineNorm, proc.mask] = normalizeLine( ...
                meas.lineRaw, ...
                app.cfg.line.offset, ...
                app.cfg.line.scale, ...
                app.cfg.line.threshold);
        
            proc.error   = computeError(proc.lineNorm, proc.mask, app.cfg.line.positions);
            proc.quality = lineQuality(proc.mask, proc.lineNorm);
        
            % ===== 3) ACCUMULATE ENCODER (MATLAB) =====
            app.appState.encTotal = uint32(app.appState.encTotal) + uint32(meas.encCount);
        
            % ===== 4) OPTIONAL: OBSTACLE OVERRIDE -> road 3 =====
            % Comment this block if your meas.ultra is not valid yet.
            if isfield(meas,"ultra") && ~isempty(meas.ultra)
                avoidDist = 250; % mm
                if double(meas.ultra) < avoidDist && app.appState.currentRoadId ~= 3
                    app.appState.prevRoadId    = app.appState.currentRoadId;
                    app.appState.currentRoadId = 3; % OBSTACLE_AVOID
                    app.roadRt = initRoadRuntime();
                    app.roadRt.enterEncTotal = app.appState.encTotal;
                end
            end
        
            % ===== 5) ROAD FSM =====
            [app.appState, app.roadRt] = roadFSM_update(app.appState, app.roadRt, app.roadMap, meas, proc);
            curRoad = app.roadMap(app.appState.currentRoadId);
        
            % ===== 6) CONTROL FROM ROAD (tune embedded) =====
            app.appState.ctrl = roadControlStep(curRoad, app.appState, proc, app.cfg);
        
            % ===== 7) SEND CONTROL =====
            app.net.sendControl( ...
                app.appState.ctrl.speed_u16, ...
                app.appState.ctrl.steerDeg, ...
                app.appState.ctrl.cmd );
        
            % ===== 8) STOP ROAD: stop timer after sending STOP =====
            if string(curRoad.type) == "STOP"
                app.stopFlag = true;
                app.stopTimer();
                return;
            end
        
            % ===== 9) LOG / BUFFER =====
            frame = struct();
            frame.t     = toc(app.t0);
            frame.meas  = meas;
            frame.proc  = proc;
            frame.ctrl  = app.appState.ctrl;
        
            frameBufferPush(app.frameBuf, frame);
            app.runLog.frames(end+1) = frame;
        
            % ===== 10) PLOT =====
            app.updatePlots(frame);
        
            app.frameIdx = app.frameIdx + 1;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.startup();
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            % sửa thông số theo bạn
            btName = "Behind the scream";
            ch = 1;

    app.net.connectBluetooth(btName, ch);
            disp("[monitor] Connect pressed");
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            disp("[monitor] Start pressed");
            if isempty(app.net) || ~app.net.isConnected()
                disp("[monitor] Not connected"); return;
            end

            app.appState.controllerType = string(app.ControllerDropDown.Value);
            runMode = string(app.RunModeDropDown.Value); %#ok<NASGU>

            app.stopFlag = false;
            app.t0 = tic;
        
            app.appState.encTotal = uint32(0);
            app.appState.currentRoadId = 1;
            app.appState.prevRoadId    = 1;
            app.roadRt = initRoadRuntime();
            app.roadRt.enterEncTotal = app.appState.encTotal;

        
            app.startTimer();

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            disp("[monitor] Stop pressed");
            app.stopFlag = true;
            app.stopTimer();
        
            if ~isempty(app.net) && app.net.isConnected()
                app.net.sendControl(uint16(0), uint16(app.cfg.steer.centerDeg), app.cfg.proto.CMD_STOP);
                app.net.sendControl(uint16(0), uint16(app.cfg.steer.centerDeg), uint16(hex2dec('FE'))); % exit op
                app.net.waitAck(1.0);
            end
        
            app.t0 = uint64(0);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ErrorAxes = uiaxes(app.UIFigure);
            title(app.ErrorAxes, 'Error')
            xlabel(app.ErrorAxes, 'X')
            ylabel(app.ErrorAxes, 'Y')
            zlabel(app.ErrorAxes, 'Z')
            app.LineAxes = uiaxes(app.UIFigure);
            title(app.LineAxes, 'Line')
            xlabel(app.LineAxes, 'X')
            ylabel(app.LineAxes, 'Y')
            zlabel(app.LineAxes, 'Z')
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.Text = 'Save';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RunModeDropDownLabel = uilabel(app.UIFigure);
            app.RunModeDropDownLabel.Text = 'Run Mode';
            app.RunModeDropDown = uidropdown(app.UIFigure);
            app.RunModeDropDown.Items = {'Auto', 'Manual', 'Stop'};
            app.RunModeDropDown.Value = 'Auto';
            app.ControllerDropDownLabel = uilabel(app.UIFigure);
            app.ControllerDropDownLabel.Text = 'Controller';
            app.ControllerDropDown = uidropdown(app.UIFigure);
            app.ControllerDropDown.Items = {'PID', 'ONOFF'};
            app.ControllerDropDown.Value = 'PID';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = monitor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end